classdef bode_diagram_visu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Bode_Diagram_ConstructionUIFigure  matlab.ui.Figure
        UIAxes                    matlab.ui.control.UIAxes
        UIAxes2                   matlab.ui.control.UIAxes
        UIAxes3                   matlab.ui.control.UIAxes
        SinusInputFrequencyPanel  matlab.ui.container.Panel
        InputFrequencyradsLabel   matlab.ui.control.Label
        InputFreqSlider           matlab.ui.control.Slider
        LabelFreq                 matlab.ui.control.Label
        InputLabel                matlab.ui.control.Label
        OutputLabel               matlab.ui.control.Label
        SystemOrderDropDownLabel  matlab.ui.control.Label
        SystemOrderDropDown       matlab.ui.control.DropDown
        UIAxes4                   matlab.ui.control.UIAxes
        ClearBodeButton_2         matlab.ui.control.Button
        OscilloscopeDisplayPanel  matlab.ui.container.Panel
        TimesDivKnobLabel         matlab.ui.control.Label
        TimesDivKnob              matlab.ui.control.Knob
        text26                    matlab.ui.control.Label
        text25                    matlab.ui.control.Label
    end

    
    properties (Access = private)
        bode_info % Description
        sys % Description
    end
    
    methods (Access = private)
        
        function update_figure1(app)
            t = 0:0.01:10;
            w = 10^(app.InputFreqSlider.Value)*app.TimesDivKnob.Value;
            A = 1;
            e = A*sin(w.*t);
            K = 1;
            tau = 1;
            s = K*A/sqrt(w^2*tau^2+1)*sin(w.*t-atan(w*tau));
            cla(app.UIAxes)
            plot(app.UIAxes, t, e, 'LineWidth', 1.1)
            hold(app.UIAxes,'on')
            plot(app.UIAxes, t, s,'LineWidth',1.1,'Color',[1    1    0.4588])
            ylim(app.UIAxes,[-1.5 1.5])
            xlim(app.UIAxes,[0 10])
            grid(app.UIAxes,"on")
            daspect(app.UIAxes, [2 1 1])
        end
        function update_figure2(app)
            value = app.SystemOrderDropDown.Value;
            if str2double(value) == 1
                tau = 1;
                hold(app.UIAxes2,'on')
                w = 10^(app.InputFreqSlider.Value);
                loglog(app.UIAxes2, w, 1/sqrt(w^2+1),'r+')
                hold(app.UIAxes3,'on')
                semilogx(app.UIAxes3, w, -atan(w*tau)*180/pi,'r+')
            else
                w0 = 1;
                xi = 0.1;
                hold(app.UIAxes2,'on')
                w = 10^(app.InputFreqSlider.Value);
                loglog(app.UIAxes2, w, 1/sqrt((1-(w/w0)^2)^2+(2*xi*w/w0)^2),'r+')
                hold(app.UIAxes3,'on')
                semilogx(app.UIAxes3, w, -atan2((2*w/w0*xi),(1-(w/w0)^2))*180/pi,'r+')
                
            end
        end
        function update_bode_bckgd(app)
            loglog(app.UIAxes2, app.bode_info.wout, app.bode_info.mag)
            semilogx(app.UIAxes3, app.bode_info.wout, app.bode_info.phase)
            grid(app.UIAxes3,'on')
            grid(app.UIAxes2,'on')
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            disableDefaultInteractivity(app.UIAxes)
            disableDefaultInteractivity(app.UIAxes2)
            disableDefaultInteractivity(app.UIAxes3)
            disableDefaultInteractivity(app.UIAxes4)
            app.UIAxes.Toolbar.Visible='off';
            app.UIAxes2.Toolbar.Visible='off';
            app.UIAxes3.Toolbar.Visible='off';
            app.UIAxes4.Toolbar.Visible='off';
            movegui(app.Bode_Diagram_ConstructionUIFigure, "center");
            app.sys = tf(1, [1 1]);
            [mag, phase, wout] = bode(app.sys);
            app.bode_info.mag = reshape(mag,[55,1]);
            app.bode_info.phase = reshape(phase,[55,1]);
            app.bode_info.wout = wout;
            update_figure1(app)
            app.LabelFreq.Text = num2str(round(10^(app.InputFreqSlider.Value), 2));
            update_bode_bckgd(app)
            %%
            app.UIAxes4.Color=[0.94;0.94;0.94];
            hold(app.UIAxes4,"on")
            plot(app.UIAxes4, -1.5:0.1:2*pi-1.5,0.5*sin(0:0.1:2*pi),'Color',[0, 0.4470, 0.7410],'Linewidth',1.5)
            quiver(app.UIAxes4, 2*pi-0.3,0,pi,0,0,'Linewidth',1.2,'Color','k')
            rectangle(app.UIAxes4, 'Position',[3*pi -0.5 2*pi 1],'Linewidth',1.2,'EdgeColor','k')
            quiver(app.UIAxes4, 5*pi+0.3,0,pi,0,0,'Linewidth',1.2,'Color','k')
            plot(app.UIAxes4, 6*pi+1.5:0.1:8*pi+1.5,0.5*sin(6*pi:0.1:8*pi),'Color',[0.9290, 0.6940, 0.1250],'Linewidth',1.5)
        end

        % Value changing function: InputFreqSlider
        function InputFreqSliderValueChanging(app, event)
            changingValue = event.Value;
        end

        % Value changed function: InputFreqSlider
        function InputFreqSliderValueChanged(app, event)
            update_figure1(app)
            app.LabelFreq.Text = num2str(round(10^(app.InputFreqSlider.Value), 2));
            update_figure2(app)
        end

        % Value changed function: TimesDivKnob
        function TimesDivKnobValueChanged(app, event)
            update_figure1(app)
        end

        % Button pushed function: ClearBodeButton_2
        function ClearBodeButton_2Pushed(app, event)
            % Delete Bode Graphs & Points
            cla(app.UIAxes2)
            cla(app.UIAxes3)

            % Recreate Bode Graphs
            update_bode_bckgd(app)
        end

        % Value changed function: SystemOrderDropDown
        function SystemOrderDropDownValueChanged(app, event)
            value = app.SystemOrderDropDown.Value;
            if str2double(value) == 1
                app.sys = tf(1, [1 1]);
            else
                xi = 0.1;
                w0 = 1;
                app.sys = tf(1, [1 2*xi/w0 1/w0^2]);
            end
            [mag, phase, wout] = bode(app.sys, {0.01, 100});
            app.bode_info.mag = reshape(mag,[length(wout), 1]);
            app.bode_info.phase = reshape(phase,[length(wout), 1]);
            app.bode_info.wout = wout;
            app.ClearBodeButton_2Pushed()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Bode_Diagram_ConstructionUIFigure = uifigure('Visible', 'off');
            app.Bode_Diagram_ConstructionUIFigure.Name = 'Bode_Diagram_Construction';
            app.UIAxes = uiaxes(app.Bode_Diagram_ConstructionUIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Magnitude')
            app.UIAxes.XTick = [0 1 2 3 4 5 6 7 8 9 10];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [-5 -3 -1 1 3 5];
            app.UIAxes.YTickLabel = '';
            app.UIAxes2 = uiaxes(app.Bode_Diagram_ConstructionUIFigure);
            title(app.UIAxes2, 'Bode Diagram')
            xlabel(app.UIAxes2, 'Frequency \omega (rad/s)')
            ylabel(app.UIAxes2, 'Magnitude (dB)')
            app.UIAxes3 = uiaxes(app.Bode_Diagram_ConstructionUIFigure);
            title(app.UIAxes3, '')
            xlabel(app.UIAxes3, 'Frequency \omega (rad/s)')
            ylabel(app.UIAxes3, 'Phase (deg)')
            app.SinusInputFrequencyPanel = uipanel(app.Bode_Diagram_ConstructionUIFigure);
            app.SinusInputFrequencyPanel.Title = 'Sinus Input Frequency';
            app.InputFrequencyradsLabel = uilabel(app.SinusInputFrequencyPanel);
            app.InputFrequencyradsLabel.Text = 'Input Frequency (rad/s)';
            app.InputFreqSlider = uislider(app.SinusInputFrequencyPanel);
            app.InputFreqSlider.Limits = [-2 2];
            app.InputFreqSlider.MajorTicks = [-2 -1 0 1 2];
            app.InputFreqSlider.MajorTickLabels = {'0.01', '0.1', '1', '10', '100'};
            app.InputFreqSlider.ValueChangedFcn = createCallbackFcn(app, @InputFreqSliderValueChanged, true);
            app.InputFreqSlider.ValueChangingFcn = createCallbackFcn(app, @InputFreqSliderValueChanging, true);
            app.InputFreqSlider.MinorTicks = [];
            app.LabelFreq = uilabel(app.SinusInputFrequencyPanel);
            app.InputLabel = uilabel(app.SinusInputFrequencyPanel);
            app.InputLabel.Text = 'Input';
            app.OutputLabel = uilabel(app.SinusInputFrequencyPanel);
            app.OutputLabel.Text = 'Output';
            app.SystemOrderDropDownLabel = uilabel(app.SinusInputFrequencyPanel);
            app.SystemOrderDropDownLabel.Text = 'System Order';
            app.SystemOrderDropDown = uidropdown(app.SinusInputFrequencyPanel);
            app.SystemOrderDropDown.Items = {'1', '2'};
            app.SystemOrderDropDown.ValueChangedFcn = createCallbackFcn(app, @SystemOrderDropDownValueChanged, true);
            app.SystemOrderDropDown.Value = '1';
            app.UIAxes4 = uiaxes(app.Bode_Diagram_ConstructionUIFigure);
            title(app.UIAxes4, '')
            xlabel(app.UIAxes4, '')
            ylabel(app.UIAxes4, '')
            app.ClearBodeButton_2 = uibutton(app.Bode_Diagram_ConstructionUIFigure, 'push');
            app.ClearBodeButton_2.ButtonPushedFcn = createCallbackFcn(app, @ClearBodeButton_2Pushed, true);
            app.ClearBodeButton_2.Text = 'Clear Bode';
            app.OscilloscopeDisplayPanel = uipanel(app.Bode_Diagram_ConstructionUIFigure);
            app.OscilloscopeDisplayPanel.Title = 'Oscilloscope Display';
            app.TimesDivKnobLabel = uilabel(app.OscilloscopeDisplayPanel);
            app.TimesDivKnobLabel.Text = 'Time (s) / Div.';
            app.TimesDivKnob = uiknob(app.OscilloscopeDisplayPanel, 'continuous');
            app.TimesDivKnob.Limits = [1 10];
            app.TimesDivKnob.MajorTicks = [1 2 5 10];
            app.TimesDivKnob.MajorTickLabels = {'1', '2', '5', '10'};
            app.TimesDivKnob.ValueChangedFcn = createCallbackFcn(app, @TimesDivKnobValueChanged, true);
            app.TimesDivKnob.MinorTicks = [];
            app.TimesDivKnob.Value = 1;
            app.text26 = uilabel(app.Bode_Diagram_ConstructionUIFigure);
            app.text26.Text = ' © Carvalho Esteban | Pedagogical use';
            app.text25 = uilabel(app.Bode_Diagram_ConstructionUIFigure);
            app.text25.Text = 'Bode Diagram Construction';
            % Show the figure after all components are created
            app.Bode_Diagram_ConstructionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = bode_diagram_visu

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Bode_Diagram_ConstructionUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Bode_Diagram_ConstructionUIFigure)
        end
    end
end