classdef Inverted_pendulum < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VisualisationandcontrolofanInvertedPendulumUIFigure  matlab.ui.Figure
        Options                    matlab.ui.container.Menu
        PendMenu                   matlab.ui.container.Menu
        VersionMenu                matlab.ui.container.Menu
        FullMenu                   matlab.ui.container.Menu
        LightMenu                  matlab.ui.container.Menu
        UIAxes                     matlab.ui.control.UIAxes
        INPROGRESSLabel            matlab.ui.control.Label
        text26                     matlab.ui.control.Label
        UIAxes2                    matlab.ui.control.UIAxes
        InvertedPendulumLabel      matlab.ui.control.Label
        SimulationsPanel           matlab.ui.container.Panel
        ClosedLoopSImulationPanel  matlab.ui.container.Panel
        ControlButton              matlab.ui.control.Button
        DesiredpositionLabel       matlab.ui.control.Label
        Xref                       matlab.ui.control.NumericEditField
        OpenLoopSimulationPanel    matlab.ui.container.Panel
        StartButton                matlab.ui.control.Button
        GeneralPanel               matlab.ui.container.Panel
        TimeLabel                  matlab.ui.control.Label
        Time                       matlab.ui.control.NumericEditField
        PositionEditFieldLabel     matlab.ui.control.Label
        Position                   matlab.ui.control.NumericEditField
        AngleLabel                 matlab.ui.control.Label
        Angle                      matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        DialogApp % Description
    end
    
    properties (Access = public)
       cartpole = struct
       cartpoleDisplay
       statesDisplay
    end
    
    methods (Access = private)
        
        function state_dot = cartpole_dyn(app, state, command)
            cart = app.cartpole;
            g = cart.g;
            L = cart.pendulum.arm_length;
            m = cart.pendulum.mass;
            M = cart.cart.mass;
            d = cart.cart.damping;
            
            sx = sin(state(3));
            cx = cos(state(3));
            D = m*L*L*(M+m*(1-cx^2));
            
            state_dot(1,1) = state(2);
            state_dot(2,1) = (1/D)*(m^2*L^2*g*cx*sx + m*L^2*(m*L*state(4)^2*sx - d*state(2))) + m*L*L*(1/D)*command;
            state_dot(3,1) = state(4);
            state_dot(4,1) = (1/D)*(-(m+M)*m*g*L*sx - m*L*cx*(m*L*state(4)^2*sx - d*state(2))) - m*L*cx*(1/D)*command;
        end
        
        function disp_states(app, t, states, i)
            pos = get(app.UIAxes2,'Position');
            app.UIAxes2.XLim = [0 t(end)];
            app.UIAxes2.YLim = [min(min(states))-0.5 max(max(states))+0.5];
            set(app.statesDisplay, 'XData', t(1:i), {'YData'}, {states(1:i, 1);states(1:i, 2);states(1:i, 3);states(1:i, 4)})
            set(app.UIAxes2, 'Position', pos)
        end
        
        function display_t(app, t, states)
            if strcmp(app.LightMenu.Checked, 'on')
                disp_states(app, t, states, length(t));
            end
            for i = 2:length(t)
                disp_cartpole(app, states(i,:));
                if strcmp(app.FullMenu.Checked, 'on')
                    disp_states(app, t, states, i);
                end
            end
        end

        function [values, isterminal, direction] = ode_event(app, t, state, start)
             values(1) = t;
             values(2) = toc(start) < 10;
             isterminal = true(size(values));
             direction = zeros(size(values));
        end

    end
    
    methods (Access = public)
        function disp_cartpole(app, state)
            x = state(1);
            theta = state(3);
            
            % Get data from struct
            al = app.cartpole.pendulum.arm_length;
            mr = app.cartpole.pendulum.mass_radius;
            cw = app.cartpole.cart.cart_width; 
            ch = app.cartpole.cart.cart_height; 
            wr = app.cartpole.cart.wheel_radius; 
            
            y = wr/2+ch/2;
            
            % Positions of elements
            cartx1 = x-cw/2;
            cartx2 = y-ch/2;
            carty1 = cw;
            carty2 = ch;
            cart_pos = [cartx1 cartx2 carty1 carty2];
            pend_pos1 = [x x+al*sin(theta)];
            pend_pos2 = [wr/2+ch/2 wr/2+ch/2 - al*cos(theta)];
            wheel1_pos = [x-cw/2 0 wr wr];
            wheel2_pos = [x+cw/2-wr 0 wr wr];
            mass_pos = [x+al*sin(theta)-mr/2 y-al*cos(theta)-mr/2 mr mr];
            
            % Update elements
            set(app.cartpoleDisplay.cart, 'Position', cart_pos);
            set(app.cartpoleDisplay.wheel1,'Position', wheel1_pos);
            set(app.cartpoleDisplay.wheel2,'Position', wheel2_pos);
            set(app.cartpoleDisplay.pendulum ,'XData', pend_pos1, 'YData', pend_pos2);
            set(app.cartpoleDisplay.mass,'Position', mass_pos);
            
            pause(0.0001)
            
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIAxes.Toolbar.Visible = 'off';
            disableDefaultInteractivity(app.UIAxes)
            state_0 = [0; 0; pi; 0.5];
            
            % Create cartpole structure
            app.cartpole.pendulum.arm_length = 2.5;
            app.cartpole.pendulum.mass_radius = 0.3;
            app.cartpole.pendulum.mass = 1;
            app.cartpole.cart.mass = 5;
            app.cartpole.cart.cart_width = 2; 
            app.cartpole.cart.cart_height = 1; 
            app.cartpole.cart.wheel_radius = 0.5;
            app.cartpole.cart.damping = 1;
            app.cartpole.g = 9.81;
            
            m_pend = app.cartpole.pendulum.mass;
            m_cart = app.cartpole.cart.mass; 
            d = app.cartpole.cart.damping;
            g = app.cartpole.g ;
            l_pend = app.cartpole.pendulum.arm_length;
            
            app.statesDisplay = plot(app.UIAxes2, 0, [0;0;0;0]);
            legend(app.UIAxes2, '$x$','$V_x$', '$\theta$', '$\dot{\theta}$','Interpreter','latex','Location',"southeastoutside")
            hold(app.UIAxes,'on');
            
            % Draw floor
            plot(app.UIAxes, [-10 10], [-0.01 -0.01], 'k'),
            
            % Draw cart
            app.cartpoleDisplay.cart = rectangle(app.UIAxes, 'Position', [0 0 0 0], 'Curvature', 0.2, 'EdgeColor', 'none', 'FaceColor', [0.4 0.5 1]);
            
            % Draw wheels
            app.cartpoleDisplay.wheel1 = rectangle(app.UIAxes,'Position', [0 0 0 0], 'Curvature',1,'FaceColor',[0 0 0],'LineWidth',1.5); 
            app.cartpoleDisplay.wheel2 = rectangle(app.UIAxes,'Position', [0 0 0 0],'Curvature',1,'FaceColor',[0 0 0],'LineWidth',1.5); 
            
            % Draw pendulum
            app.cartpoleDisplay.pendulum = line(app.UIAxes, [0 0], [0 0],'LineWidth',5, 'Color',[0.72 0.45 0.33]); 
            
            % Draw pendulum mass
            app.cartpoleDisplay.mass = rectangle(app.UIAxes,'Position', [0 0 0 0],'Curvature',1,'FaceColor',[0.75 0.75 0.75], 'EdgeColor', [0.75 0.75 0.75]);
            
            % Display cartpole
            disp_cartpole(app, state_0)
            disp_states(app, app.Time.Value, [state_0' state_0'], 1);
            
            % Control
            A = [0 1 0 0;
            0 -d/m_cart m_pend*g/m_cart 0;
            0 0 0 1;
            0 -d/(m_cart*l_pend) (m_cart+m_pend)*g/(m_cart*l_pend) 0];
            B = [0; 1/m_cart; 0; 1/(m_cart*l_pend)];
            Q = diag([1,1,10,100]);
            R = 0.0001;
            app.cartpole.control_feedback = lqr(A,B,Q,R);

        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.StartButton.Enable = 'off';
            app.ControlButton.Enable = 'off';

            x0 = app.Position.Value;
            theta0 = app.Angle.Value;
            state_0 = [x0; 0; theta0; 0.5];

            time = 0:0.1:app.Time.Value;

            [t, states] = ode45(@(t, state) cartpole_dyn(app, state, 0), time, state_0);

            display_t(app, t, states)
            
            app.StartButton.Enable = 'on';
            app.ControlButton.Enable = 'on';
        end

        % Value changed function: Position
        function PositionValueChanged(app, event)
            x0 = app.Position.Value;
            theta0 = app.Angle.Value;
            state_0 = [x0; 0; theta0; 0.5];
            disp_cartpole(app, state_0)
        end

        % Button pushed function: ControlButton
        function ControlButtonPushed(app, event)
            app.StartButton.Enable = 'off';
            app.ControlButton.Enable = 'off';

            x0 = app.Position.Value;
            theta0 = app.Angle.Value;

            state_ref = [app.Xref.Value; 0; pi; 0];
            state_0 = [x0; 0; theta0; 0.5];
            time = 0:0.1:app.Time.Value;
            
            start = tic;
            opt = odeset('Events', @(t, state) ode_event(app, t, state, start));
                    
            [t, states] = ode45(@(t, state) cartpole_dyn(app, state, -app.cartpole.control_feedback*(state-state_ref)), time, state_0, opt);

            display_t(app, t, states);

            app.StartButton.Enable = 'on';
            app.ControlButton.Enable = 'on';
        end

        % Value changed function: Angle
        function AngleValueChanged(app, event)
            x0 = app.Position.Value;
            theta0 = app.Angle.Value;
            state_0 = [x0; 0; theta0; 0.5];
            disp_cartpole(app, state_0)
        end

        % Menu selected function: Options
        function OptionsMenuSelected(app, event)
            
        end

        % Close request function: 
        % VisualisationandcontrolofanInvertedPendulumUIFigure
        function VisualisationandcontrolofanInvertedPendulumUIFigureCloseRequest(app, event)
            delete(app.DialogApp)
            delete(app)
        end

        % Menu selected function: PendMenu
        function PendMenuSelected(app, event)
            app.Angle.Enable = 'off';
            app.Position.Enable = 'off';
            app.Time.Enable = 'off';
            app.ControlButton.Enable = 'off';
            app.StartButton.Enable = 'off';
            app.Options.Enable = 'off';
            app.DialogApp = options(app);
           
        end

        % Menu selected function: LightMenu
        function LightMenuSelected(app, event)
            app.LightMenu.Checked =  'on';
            app.FullMenu.Checked =  'off';
        end

        % Menu selected function: FullMenu
        function FullMenuSelected(app, event)
            app.LightMenu.Checked =  'off';
            app.FullMenu.Checked =  'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.VisualisationandcontrolofanInvertedPendulumUIFigure = uifigure('Visible', 'off');
            app.VisualisationandcontrolofanInvertedPendulumUIFigure.Name = 'Visualisation and control of an Inverted Pendulum';
            app.VisualisationandcontrolofanInvertedPendulumUIFigure.CloseRequestFcn = createCallbackFcn(app, @VisualisationandcontrolofanInvertedPendulumUIFigureCloseRequest, true);
            app.Options = uimenu(app.VisualisationandcontrolofanInvertedPendulumUIFigure);
            app.Options.MenuSelectedFcn = createCallbackFcn(app, @OptionsMenuSelected, true);
            app.Options.Text = 'Options';
            app.PendMenu = uimenu(app.Options);
            app.PendMenu.MenuSelectedFcn = createCallbackFcn(app, @PendMenuSelected, true);
            app.PendMenu.Text = 'Pendulm';
            app.VersionMenu = uimenu(app.Options);
            app.VersionMenu.Text = 'Version';
            app.FullMenu = uimenu(app.VersionMenu);
            app.FullMenu.MenuSelectedFcn = createCallbackFcn(app, @FullMenuSelected, true);
            app.FullMenu.Checked = 'on';
            app.FullMenu.Text = 'Full';
            app.LightMenu = uimenu(app.VersionMenu);
            app.LightMenu.MenuSelectedFcn = createCallbackFcn(app, @LightMenuSelected, true);
            app.LightMenu.Text = 'Light';
            app.UIAxes = uiaxes(app.VisualisationandcontrolofanInvertedPendulumUIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XLim = [-10 10];
            app.UIAxes.YLim = [-2.5 6.5];
            app.UIAxes.ZLim = [0 1];
            app.UIAxes.CLim = [0 1];
            app.INPROGRESSLabel = uilabel(app.VisualisationandcontrolofanInvertedPendulumUIFigure);
            app.INPROGRESSLabel.Text = 'IN PROGRESS';
            app.text26 = uilabel(app.VisualisationandcontrolofanInvertedPendulumUIFigure);
            app.text26.Text = ' © Carvalho Esteban | Pedagogical use';
            app.UIAxes2 = uiaxes(app.VisualisationandcontrolofanInvertedPendulumUIFigure);
            title(app.UIAxes2, 'States Evolution')
            xlabel(app.UIAxes2, 'Time (s)')
            ylabel(app.UIAxes2, '')
            app.InvertedPendulumLabel = uilabel(app.VisualisationandcontrolofanInvertedPendulumUIFigure);
            app.InvertedPendulumLabel.Text = 'Inverted Pendulum';
            app.SimulationsPanel = uipanel(app.VisualisationandcontrolofanInvertedPendulumUIFigure);
            app.SimulationsPanel.Title = 'Simulations';
            app.ClosedLoopSImulationPanel = uipanel(app.SimulationsPanel);
            app.ClosedLoopSImulationPanel.Title = 'Closed Loop SImulation';
            app.ControlButton = uibutton(app.ClosedLoopSImulationPanel, 'push');
            app.ControlButton.ButtonPushedFcn = createCallbackFcn(app, @ControlButtonPushed, true);
            app.ControlButton.Text = 'Control';
            app.DesiredpositionLabel = uilabel(app.ClosedLoopSImulationPanel);
            app.DesiredpositionLabel.Text = 'Desired position';
            app.Xref = uieditfield(app.ClosedLoopSImulationPanel, 'numeric');
            app.Xref.Value = 2;
            app.OpenLoopSimulationPanel = uipanel(app.SimulationsPanel);
            app.OpenLoopSimulationPanel.Title = 'Open Loop Simulation';
            app.StartButton = uibutton(app.OpenLoopSimulationPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.GeneralPanel = uipanel(app.SimulationsPanel);
            app.GeneralPanel.Title = 'General';
            app.TimeLabel = uilabel(app.GeneralPanel);
            app.TimeLabel.Text = 'Time';
            app.Time = uieditfield(app.GeneralPanel, 'numeric');
            app.Time.Value = 10;
            app.PositionEditFieldLabel = uilabel(app.GeneralPanel);
            app.PositionEditFieldLabel.Text = 'Position';
            app.Position = uieditfield(app.GeneralPanel, 'numeric');
            app.Position.ValueChangedFcn = createCallbackFcn(app, @PositionValueChanged, true);
            app.AngleLabel = uilabel(app.GeneralPanel);
            app.AngleLabel.Text = 'Angle';
            app.Angle = uieditfield(app.GeneralPanel, 'numeric');
            app.Angle.ValueChangedFcn = createCallbackFcn(app, @AngleValueChanged, true);
            app.Angle.Value = 3.14;
            % Show the figure after all components are created
            app.VisualisationandcontrolofanInvertedPendulumUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Inverted_pendulum

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.VisualisationandcontrolofanInvertedPendulumUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VisualisationandcontrolofanInvertedPendulumUIFigure)
        end
    end
end