classdef PID_visu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1             matlab.ui.Figure
        graphique           matlab.ui.control.UIAxes
        uipanel2            matlab.ui.container.Panel
        const_wn            matlab.ui.control.EditField
        text5               matlab.ui.control.Label
        text6               matlab.ui.control.Label
        text7               matlab.ui.control.Label
        const_z             matlab.ui.control.EditField
        const_k             matlab.ui.control.EditField
        popupmenu1          matlab.ui.control.DropDown
        text41              matlab.ui.control.Label
        text42              matlab.ui.control.Label
        panel_1             matlab.ui.container.Panel
        uipanel4_2          matlab.ui.container.Panel
        uipanel5_2          matlab.ui.container.Panel
        text9_2             matlab.ui.control.Label
        text_k_2            matlab.ui.control.Label
        panel_2             matlab.ui.container.Panel
        uipanel4_5          matlab.ui.container.Panel
        uipanel5_5          matlab.ui.container.Panel
        text_k_5            matlab.ui.control.Label
        numEditFieldLabel   matlab.ui.control.Label
        numEditField        matlab.ui.control.EditField
        denEditFieldLabel   matlab.ui.control.Label
        denEditField        matlab.ui.control.EditField
        panel_3             matlab.ui.container.Panel
        uipanel11           matlab.ui.container.Panel
        uipanel7            matlab.ui.container.Panel
        uipanel4_4          matlab.ui.container.Panel
        uipanel5_4          matlab.ui.container.Panel
        uipanel12           matlab.ui.container.Panel
        uipanel8            matlab.ui.container.Panel
        text16              matlab.ui.control.Label
        text14              matlab.ui.control.Label
        text15              matlab.ui.control.Label
        text13              matlab.ui.control.Label
        text11              matlab.ui.control.Label
        text10              matlab.ui.control.Label
        text9_3             matlab.ui.control.Label
        text_k_4            matlab.ui.control.Label
        text17              matlab.ui.control.Label
        text18              matlab.ui.control.Label
        uipanel1            matlab.ui.container.Panel
        kd_max              matlab.ui.control.Label
        Ti_max              matlab.ui.control.Label
        Td_max              matlab.ui.control.Label
        Kd_min              matlab.ui.control.Label
        Ti_min              matlab.ui.control.Label
        Td_min              matlab.ui.control.Label
        Td_value            matlab.ui.control.Slider
        Kd_value            matlab.ui.control.Slider
        text2               matlab.ui.control.Label
        text3               matlab.ui.control.Label
        text4               matlab.ui.control.Label
        Ti_value            matlab.ui.control.Slider
        kd_val              matlab.ui.control.EditField
        ti_val              matlab.ui.control.EditField
        td_val              matlab.ui.control.EditField
        uipanel3            matlab.ui.container.Panel
        P_correcteur        matlab.ui.control.CheckBox
        PI_correcteur       matlab.ui.control.CheckBox
        PID_correcteur      matlab.ui.control.CheckBox
        text27              matlab.ui.control.Label
        text28              matlab.ui.control.Label
        uipanel17           matlab.ui.container.Panel
        uipanel18           matlab.ui.container.Panel
        text30              matlab.ui.control.Label
        text29              matlab.ui.control.Label
        text31              matlab.ui.control.Label
        text32              matlab.ui.control.Label
        text35              matlab.ui.control.Label
        uipanel19           matlab.ui.container.Panel
        uipanel20           matlab.ui.container.Panel
        text36              matlab.ui.control.Label
        text66              matlab.ui.control.Label
        text67              matlab.ui.control.Label
        text25              matlab.ui.control.Label
        text26              matlab.ui.control.Label
        uipanel21           matlab.ui.container.Panel
        text37              matlab.ui.control.Label
        text38              matlab.ui.control.Label
        tr5_txt             matlab.ui.control.EditField
        err_txt             matlab.ui.control.EditField
        text39              matlab.ui.control.Label
        dep_txt             matlab.ui.control.EditField
        text65              matlab.ui.control.Label
        text65_2            matlab.ui.control.Label
        LangageSwitchLabel  matlab.ui.control.Label
        LangageSwitch       matlab.ui.control.Switch
    end

    
    properties (Access = private)
        system_function = tf(1,[1 1]); % Description
    end
    
    methods (Access = private)
        function update_figure(app)

            % Recupere les parametres
            Kd = get(app.Kd_value, 'Value');
            Ti = get(app.Ti_value, 'Value');
            Td = get(app.Td_value, 'Value');
            
            K = str2double(get(app.const_k, 'Value'));
            z = str2double(get(app.const_z, 'Value'));
            wn = str2double(get(app.const_wn, 'Value'));
            
            % Choix du correcteur
            p_bool = get(app.P_correcteur, 'Value');
            pi_bool = get(app.PI_correcteur, 'Value');
            pid_bool = get(app.PID_correcteur, 'Value');
            
            % Construction de la fonction de transfert
            if strcmp(get(app.popupmenu1, 'Value'), '1er ordre')
                app.system_function = tf(K, [1 z]);
            elseif strcmp(get(app.popupmenu1, 'Value'), '2ieme ordre')
                app.system_function = tf(K, [1/wn^2 2*z/wn 1]);
            else
                app.system_function = tf(str2num(app.numEditField.Value), str2num(app.denEditField.Value));
            end
            
            % Gestion de l'affichage
            if ~p_bool && ~pi_bool && ~pid_bool
                % Boucle ouverte sans correcteur
                [y, t] = step(app.system_function);
                plot(app.graphique, t, y(:, :, 1));
                  
                % Gestion de la langue
                if strcmp(app.LangageSwitch.Value,'FR')
                    app.graphique.Title.String = 'Reponse indicielle du systeme';
                else
                    app.graphique.Title.String = 'System step response';
                end
                all_info = stepinfo(app.system_function, 'SettleTimeThreshold', 0.05);
            else
                % Construction du controller
                if p_bool
                    PID = Kd;
                elseif pi_bool
                    PID = Kd*tf([Ti 1], [Ti 0]);
                elseif pid_bool
                    PID = Kd*tf([Ti*Td Ti+Td 1], [Ti 0]);
                end
                
                % Boucle fermée
                CL = feedback(PID*app.system_function, 1);
                [y, t] = step(CL);
                plot(app.graphique, t, y(:,:,1));
                
                all_info = stepinfo(CL,'SettleTimeThreshold', 0.05);
                
                % Gestion de la langue
                if strcmp(app.LangageSwitch.Value,'FR')
                    app.graphique.Title.String = 'Reponse indicielle du systeme en boucle fermee';
                else
                    app.graphique.Title.String = 'Closed Loop Step Response';
                end
            end

          stime = round(all_info.SettlingTime, 2);
          set(app.tr5_txt, 'Value', num2str(stime));
          set(app.err_txt, 'Value', num2str(round(abs(y(end, end, 1)-1), 2)));
          set(app.dep_txt, 'Value', num2str(round(all_info.Overshoot, 1)));
          disableDefaultInteractivity(app.graphique)
          
          if strcmp(app.LangageSwitch.Value,'FR')
             app.graphique.XLabel.String = 'Temps (s)';
             app.graphique.YLabel.String = 'Amplitude';
          else
             app.graphique.XLabel.String = 'Time (s)';
             app.graphique.YLabel.String = 'Amplitude';
          end
          app.graphique.XGrid = 'on';
          app.graphique.YGrid = 'on';
          app.graphique.XLim = [t(1) t(end)];          
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Controllers_App_OpeningFcn(app)
%             % This function has no output args, see OutputFcn.
%             % hObject    handle to figure
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % app    structure with app and user data (see GUIDATA)
%             % varargin   unrecognized PropertyName/PropertyValue pairs from the
%             %            command line (see VARARGIN)
%             
%             app = update_figure(app);
%             
%             % Choose default command line output for Controllers_App
%             app.output = hObject;
%             
%             % Update app structure
%             guidata(hObject, app);
          movegui(app.figure1,"center");

          update_figure(app);
        end

        % Value changed function: Kd_value
        function Kd_value_Callback(app, event)

            
            set(app.kd_val,'Value',num2str(round(get(app.Kd_value,'Value'),2)));
            update_figure(app);
            
        end

        % Value changed function: PID_correcteur
        function PID_correcteur_Callback(app, event)

            
            if ~get(app.PID_correcteur,'Value')
                set(app.Kd_value,'Enable','off');
                set(app.Ti_value,'Enable','off');
                set(app.Td_value,'Enable','off');
                set(app.kd_val,'Enable','off')
                set(app.ti_val,'Enable','off')
                set(app.td_val,'Enable','off')
            else
            
            set(app.P_correcteur,'Value',0);
            set(app.PI_correcteur,'Value',0);
            
            set(app.Kd_value,'Enable','on');
            set(app.Ti_value,'Enable','on');
            set(app.Td_value,'Enable','on');
            set(app.kd_val,'Enable','on')
            set(app.ti_val,'Enable','on')
            set(app.td_val,'Enable','on')
            end
            update_figure(app);
            
        end

        % Value changed function: PI_correcteur
        function PI_correcteur_Callback(app, event)

            
            if ~get(app.PI_correcteur,'Value')
                set(app.Kd_value,'Enable','off');
                set(app.Ti_value,'Enable','off');
                set(app.Td_value,'Enable','off');
                    set(app.kd_val,'Enable','off')
                set(app.ti_val,'Enable','off')
                set(app.td_val,'Enable','off')
            else
            
            set(app.P_correcteur,'Value',0);
            set(app.PID_correcteur,'Value',0);
            set(app.Kd_value,'Enable','on');
            
            set(app.Kd_value,'Enable','on');
            set(app.Ti_value,'Enable','on');
            set(app.Td_value,'Enable','off');
            
            set(app.kd_val,'Enable','on')
            set(app.ti_val,'Enable','on')
            set(app.td_val,'Enable','off')
            end
            update_figure(app);
            
        end

        % Value changed function: P_correcteur
        function P_correcteur_Callback(app, event)

            if ~get(app.P_correcteur,'Value')
                set(app.Kd_value,'Enable','off');
                set(app.Ti_value,'Enable','off');
                set(app.Td_value,'Enable','off');
                set(app.kd_val,'Enable','off')
                set(app.ti_val,'Enable','off')
                set(app.td_val,'Enable','off')
            else
            
            set(app.PI_correcteur,'Value',0);
            set(app.PID_correcteur,'Value',0);
            
            set(app.Kd_value,'Enable','on');
            set(app.Ti_value,'Enable','off');
            set(app.Td_value,'Enable','off');
            set(app.kd_val,'Enable','on')
            set(app.ti_val,'Enable','off')
            set(app.td_val,'Enable','off')
            end
            
            update_figure(app);

        end

        % Value changed function: Td_value
        function Td_value_Callback(app, event)

            set(app.td_val,'Value',num2str(round(app.Td_value.Value,2)));
            update_figure(app);
            
        end

        % Value changed function: Ti_value
        function Ti_value_Callback(app, event)

            set(app.ti_val,'Value',num2str(round(app.Ti_value.Value,2)));
            update_figure(app);
            
        end

        % Value changed function: const_k
        function const_k_Callback(app, event)

            value = round(str2num(app.const_k.Value),2);
            if(isempty(value))
                value = 1;
            end
            app.const_k.Value = num2str(value);
            update_figure(app);
        end

        % Value changed function: const_wn
        function const_wn_Callback(app, event)

            value = round(str2num(app.const_wn.Value),2);
            if(isempty(value))
                value = 1;
            end
            app.const_wn.Value = num2str(value);

            update_figure(app);

        end

        % Value changed function: const_z
        function const_z_Callback(app, event)

            value = app.const_z.Value;
            if(isempty(value))
                value = 1;
            end
            app.const_z.Value = num2str(value);

            update_figure(app);
        end

        % Value changed function: kd_val
        function kd_val_Callback(app, event)

            value = round(str2num(app.kd_val.Value),2);
            if(isempty(value))
                value = 0;
                app.kd_val.Value = 0;
            end
            if value <0
                value = 0;
            elseif value >100
                value = 100;
            end
            app.kd_val.Value = num2str(value);
            set(app.Kd_value,'Value', num2str(value));
            update_figure(app);
            
        end

        % Value changed function: popupmenu1
        function popupmenu1_Callback(app, event)

            
            if strcmp(app.popupmenu1.Value,'1er ordre')
                % Active la visibilité des parametres
                set(app.text5, 'Visible', 'on');
                set(app.text6, 'Visible', 'on');
                set(app.text7, 'Visible', 'off');
                set(app.const_k, 'Visible', 'on');
                set(app.const_z, 'Visible', 'on');
                set(app.const_wn, 'Visible', 'off');
                
                % Gestion texte
                set(app.text6, 'Text', 'Constante de temps');
                
                % Gestion affichage de la fonction de transfert
                set(app.panel_1,'Visible', 'on');
                set(app.panel_2,'Visible', 'off');
                set(app.panel_3,'Visible', 'off');
                
            elseif strcmp(app.popupmenu1.Value,'1st order')
                % Active la visibilité des parametres
                set(app.text5, 'Visible', 'on');
                set(app.text6, 'Visible', 'on');
                set(app.text7, 'Visible', 'off');
                set(app.const_k, 'Visible', 'on');
                set(app.const_z, 'Visible', 'on');
                set(app.const_wn, 'Visible', 'off');
                
                % Gestion texte
                set(app.text6, 'Text', 'Time constant');
                
                % Gestion affichage de la fonction de transfert
                set(app.panel_1,'Visible', 'on');
                set(app.panel_2,'Visible', 'off');
                set(app.panel_3,'Visible', 'off');
                
                
            elseif strcmp(app.popupmenu1.Value,'2nd order')
                % Active la visibilité des parametres
                set(app.text5, 'Visible', 'on');
                set(app.text6, 'Visible', 'on');
                set(app.text7, 'Visible', 'on');
                set(app.const_k, 'Visible', 'on');
                set(app.const_z, 'Visible', 'on');
                set(app.const_wn, 'Visible', 'on');
                set(app.text6, 'Text', 'Damping coefficient');
                
                % Gestion affichage de la fonction de transfert
                set(app.panel_1,'Visible', 'off');
                set(app.panel_2,'Visible', 'off');
                set(app.panel_3,'Visible', 'on');
            elseif strcmp(app.popupmenu1.Value,'2ieme ordre')
                % Active la visibilité des parametres
                set(app.text5, 'Visible', 'on');
                set(app.text6, 'Visible', 'on');
                set(app.text7, 'Visible', 'on');
                set(app.const_k, 'Visible', 'on');
                set(app.const_z, 'Visible', 'on');
                set(app.const_wn, 'Visible', 'on');

                set(app.text6, 'Text', 'Coefficient d''amortissement');
                % Gestion affichage de la fonction de transfert
                set(app.panel_1,'Visible', 'off');
                set(app.panel_2,'Visible', 'off');
                set(app.panel_3,'Visible', 'on');
                
            elseif strcmp(app.popupmenu1.Value,'perso')
                % Supprime la visibilité des parametres
                set(app.text5, 'Visible', 'off');
                set(app.text6, 'Visible', 'off');
                set(app.text7, 'Visible', 'off');
                set(app.const_k, 'Visible', 'off');
                set(app.const_z, 'Visible', 'off');
                set(app.const_wn, 'Visible', 'off');
                
                % Gestion affichage de la fonction de transfert
                set(app.panel_1,'Visible', 'off');
                set(app.panel_2,'Visible', 'on');
                set(app.panel_3,'Visible', 'off');
            end
            update_figure(app);

        end

        % Value changed function: td_val
        function td_val_Callback(app, event)

            value = round(str2double(app.td_val.Value),2);
            
            if(isempty(value))
                value = 1;
                app.td_val.Value= '1';
            end
            
            if value <1
                value = 1;
            elseif value >100
                value = 100;
            end
            app.td_val.Value = num2str(value);
            set(app.Td_value,'Value', num2str(value));
            update_figure(app);

        end

        % Value changed function: ti_val
        function ti_val_Callback(app, event)

            value = round(str2double(app.ti_val.Value),2);
            if(isempty(value))
                value = 1;
                set(app.Ti_value,'Value',value);
            end
            if value <1
                value = 1;
            elseif value >100
                value = 100;
            end
            app.ti_val.Value = num2str(value);
            set(app.Ti_value,'Value',value);
            update_figure(app);

        end

        % Value changed function: LangageSwitch
        function LangageSwitchValueChanged(app, event)
            value = app.LangageSwitch.Value;
            update_figure(app)
            if strcmp(app.LangageSwitch.Value,'FR')
               app.LangageSwitchLabel.Text = 'Langage';
               app.uipanel2.Title='Systeme';
               app.text41.Text='Ordre du systeme';
               app.text42.Text='Fonction de transfert';
               if strcmp(app.popupmenu1.Value,'2nd order')
                   val = '2ieme ordre';
               elseif strcmp(app.popupmenu1.Value,'1st order')
                   val = '1er ordre';
               else
                   val = 'perso';
               end
               app.popupmenu1.Items = {'1er ordre','2ieme ordre','perso'};
               app.popupmenu1.Value = val;
               app.text5.Text = 'Gain statique';
               app.text7.Text = 'Pulsation naturelle';
               app.text25.Text = 'Conception et visualisation de regulateurs PID';
               app.text37.Text = 'Temps de reponse à 5%';
               app.text38.Text = 'Erreur statique';
               app.text39.Text = 'Depassement (%)';
               app.text26.Text =  '© Carvalho Esteban | Usage pedagogique';
               app.uipanel1.Title = 'Parametres du regulateur';
               app.uipanel3.Title = 'Regulateur';
               app.P_correcteur.Text = 'Regulateur P';
               app.PI_correcteur.Text = 'Regulateur PI';
               app.PID_correcteur.Text = 'Regulateur PID';
            else
               app.LangageSwitchLabel.Text = 'Language';
               app.uipanel2.Title='System';
               app.text41.Text='System order';
               app.text42.Text='Transfert function';
               if strcmp(app.popupmenu1.Value,'2ieme ordre')
                   val = '2nd order';
               elseif strcmp(app.popupmenu1.Value,'1er ordre')
                   val = '1st order';
               else
                   val = 'perso';
               end
               app.popupmenu1.Items = {'1st order','2nd order','perso'};
               app.popupmenu1.Value = val;
               app.text5.Text = 'Static gain';
               app.text7.Text = 'Natural frequency';
               app.text25.Text = 'Design and visualisation of PID controllers ';
               app.text37.Text = 'Settling Time within 5%';
               app.text38.Text = 'Steady State Error';
               app.text39.Text = 'Overshoot (%)';
               app.text26.Text =  '© Carvalho Esteban | Pedagogical use';
               app.uipanel1.Title = 'Controller parameters';
               app.uipanel3.Title = 'Controller';
               app.P_correcteur.Text = 'P Controller';
               app.PI_correcteur.Text = 'PI Controller';
               app.PID_correcteur.Text = 'PID Controller';
            end
            popupmenu1_Callback(app, event)
        end

        % Value changed function: numEditField
        function numEditFieldValueChanged(app, event)
            value = str2num(app.numEditField.Value);
            if ~isvector(value)
                value = '[1]';
                app.numEditField.Value = value;
            end
            update_figure(app);
        end

        % Value changed function: denEditField
        function denEditFieldValueChanged(app, event)
            value = str2num(app.denEditField.Value);
            if ~isvector(value)
                value = '[1, 1, 1]';
                app.denEditField.Value = value;
            end
            update_figure(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Desgin and Visualisation of PID Regulator';
            app.graphique = uiaxes(app.figure1);
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Title = 'Systeme';
            app.const_wn = uieditfield(app.uipanel2, 'text');
            app.const_wn.ValueChangedFcn = createCallbackFcn(app, @const_wn_Callback, true);
            app.const_wn.Value = '1';
            app.text5 = uilabel(app.uipanel2);
            app.text5.Text = 'Gain statique';
            app.text6 = uilabel(app.uipanel2);
            app.text6.Text = 'Coefficient d''amortissement';
            app.text7 = uilabel(app.uipanel2);
            app.text7.Text = 'Pulsation naturelle';
            app.const_z = uieditfield(app.uipanel2, 'text');
            app.const_z.ValueChangedFcn = createCallbackFcn(app, @const_z_Callback, true);
            app.const_z.Value = '1';
            app.const_k = uieditfield(app.uipanel2, 'text');
            app.const_k.ValueChangedFcn = createCallbackFcn(app, @const_k_Callback, true);
            app.const_k.Value = '1';
            app.popupmenu1 = uidropdown(app.uipanel2);
            app.popupmenu1.Items = {'1er ordre', '2ieme ordre', 'perso'};
            app.popupmenu1.ValueChangedFcn = createCallbackFcn(app, @popupmenu1_Callback, true);
            app.popupmenu1.Value = '2ieme ordre';
            app.text41 = uilabel(app.uipanel2);
            app.text41.Text = 'Ordre du systeme';
            app.text42 = uilabel(app.uipanel2);
            app.text42.Text = 'Fonction de transfert';
            app.panel_1 = uipanel(app.uipanel2);
            app.panel_1.Visible = 'off';
            app.uipanel4_2 = uipanel(app.panel_1);
            app.uipanel5_2 = uipanel(app.panel_1);
            app.text9_2 = uilabel(app.uipanel5_2);
            app.text9_2.Text = '1 + tau·p';
            app.text_k_2 = uilabel(app.panel_1);
            app.text_k_2.Text = 'K';
            app.panel_2 = uipanel(app.uipanel2);
            app.panel_2.Visible = 'off';
            app.uipanel4_5 = uipanel(app.panel_2);
            app.uipanel5_5 = uipanel(app.panel_2);
            app.text_k_5 = uilabel(app.panel_2);
            app.text_k_5.Text = '';
            app.numEditFieldLabel = uilabel(app.panel_2);
            app.numEditFieldLabel.Text = 'num';
            app.numEditField = uieditfield(app.panel_2, 'text');
            app.numEditField.ValueChangedFcn = createCallbackFcn(app, @numEditFieldValueChanged, true);
            app.numEditField.Value = '[1]';
            app.denEditFieldLabel = uilabel(app.panel_2);
            app.denEditFieldLabel.Text = 'den';
            app.denEditField = uieditfield(app.panel_2, 'text');
            app.denEditField.ValueChangedFcn = createCallbackFcn(app, @denEditFieldValueChanged, true);
            app.denEditField.Value = '[1, 1, 1]';
            app.panel_3 = uipanel(app.uipanel2);
            app.uipanel11 = uipanel(app.panel_3);
            app.uipanel7 = uipanel(app.panel_3);
            app.uipanel4_4 = uipanel(app.panel_3);
            app.uipanel5_4 = uipanel(app.panel_3);
            app.uipanel12 = uipanel(app.panel_3);
            app.uipanel8 = uipanel(app.panel_3);
            app.text16 = uilabel(app.panel_3);
            app.text16.Text = '+';
            app.text14 = uilabel(app.panel_3);
            app.text14.Text = 'p';
            app.text15 = uilabel(app.panel_3);
            app.text15.Text = 'p²';
            app.text13 = uilabel(app.panel_3);
            app.text13.Text = '1';
            app.text11 = uilabel(app.panel_3);
            app.text11.Text = '+';
            app.text10 = uilabel(app.panel_3);
            app.text10.Text = '2·z';
            app.text9_3 = uilabel(app.panel_3);
            app.text9_3.Text = '1';
            app.text_k_4 = uilabel(app.panel_3);
            app.text_k_4.Text = 'K';
            app.text17 = uilabel(app.panel_3);
            app.text17.Text = 'wn';
            app.text18 = uilabel(app.panel_3);
            app.text18.Text = 'wn²';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Parametres du regulateur';
            app.kd_max = uilabel(app.uipanel1);
            app.kd_max.Text = '100';
            app.Ti_max = uilabel(app.uipanel1);
            app.Ti_max.Text = '100';
            app.Td_max = uilabel(app.uipanel1);
            app.Td_max.Text = '100';
            app.Kd_min = uilabel(app.uipanel1);
            app.Kd_min.Text = '0';
            app.Ti_min = uilabel(app.uipanel1);
            app.Ti_min.Text = '1';
            app.Td_min = uilabel(app.uipanel1);
            app.Td_min.Text = '1';
            app.Td_value = uislider(app.uipanel1);
            app.Td_value.Limits = [1 100];
            app.Td_value.MajorTicks = [];
            app.Td_value.ValueChangedFcn = createCallbackFcn(app, @Td_value_Callback, true);
            app.Td_value.MinorTicks = [];
            app.Td_value.Enable = 'off';
            app.Td_value.Value = 1;
            app.Kd_value = uislider(app.uipanel1);
            app.Kd_value.MajorTicks = [];
            app.Kd_value.ValueChangedFcn = createCallbackFcn(app, @Kd_value_Callback, true);
            app.Kd_value.MinorTicks = [];
            app.Kd_value.Enable = 'off';
            app.Kd_value.Value = 1;
            app.text2 = uilabel(app.uipanel1);
            app.text2.Text = 'Kd =';
            app.text3 = uilabel(app.uipanel1);
            app.text3.Text = 'Ti =';
            app.text4 = uilabel(app.uipanel1);
            app.text4.Text = 'Td =';
            app.Ti_value = uislider(app.uipanel1);
            app.Ti_value.Limits = [1 100];
            app.Ti_value.MajorTicks = [];
            app.Ti_value.ValueChangedFcn = createCallbackFcn(app, @Ti_value_Callback, true);
            app.Ti_value.MinorTicks = [];
            app.Ti_value.Enable = 'off';
            app.Ti_value.Value = 1;
            app.kd_val = uieditfield(app.uipanel1, 'text');
            app.kd_val.ValueChangedFcn = createCallbackFcn(app, @kd_val_Callback, true);
            app.kd_val.Enable = 'off';
            app.kd_val.Value = '1';
            app.ti_val = uieditfield(app.uipanel1, 'text');
            app.ti_val.ValueChangedFcn = createCallbackFcn(app, @ti_val_Callback, true);
            app.ti_val.Enable = 'off';
            app.ti_val.Value = '1';
            app.td_val = uieditfield(app.uipanel1, 'text');
            app.td_val.ValueChangedFcn = createCallbackFcn(app, @td_val_Callback, true);
            app.td_val.Enable = 'off';
            app.td_val.Value = '1';
            app.uipanel3 = uipanel(app.figure1);
            app.uipanel3.Title = 'Regulateur';
            app.P_correcteur = uicheckbox(app.uipanel3);
            app.P_correcteur.ValueChangedFcn = createCallbackFcn(app, @P_correcteur_Callback, true);
            app.P_correcteur.Text = 'Regulateur P';
            app.PI_correcteur = uicheckbox(app.uipanel3);
            app.PI_correcteur.ValueChangedFcn = createCallbackFcn(app, @PI_correcteur_Callback, true);
            app.PI_correcteur.Text = 'Regulateur PI';
            app.PID_correcteur = uicheckbox(app.uipanel3);
            app.PID_correcteur.ValueChangedFcn = createCallbackFcn(app, @PID_correcteur_Callback, true);
            app.PID_correcteur.Text = 'Regulateur PID';
            app.text27 = uilabel(app.uipanel3);
            app.text27.Text = 'C(p) =';
            app.text28 = uilabel(app.uipanel3);
            app.text28.Text = 'C(p) =';
            app.uipanel17 = uipanel(app.uipanel3);
            app.uipanel18 = uipanel(app.uipanel3);
            app.text30 = uilabel(app.uipanel18);
            app.text30.Text = 'Ti·p';
            app.text29 = uilabel(app.uipanel3);
            app.text29.Text = '1 + Ti·p';
            app.text31 = uilabel(app.uipanel3);
            app.text31.Text = 'C(p) =';
            app.text32 = uilabel(app.uipanel3);
            app.text32.Text = '(1 + Ti·p)(1 + Td·p)';
            app.text35 = uilabel(app.uipanel3);
            app.text35.Text = 'Kd';
            app.uipanel19 = uipanel(app.uipanel3);
            app.uipanel20 = uipanel(app.uipanel3);
            app.text36 = uilabel(app.uipanel20);
            app.text36.Text = 'Ti·p';
            app.text66 = uilabel(app.uipanel3);
            app.text66.Text = 'Kd';
            app.text67 = uilabel(app.uipanel3);
            app.text67.Text = 'Kd';
            app.text25 = uilabel(app.figure1);
            app.text25.Text = 'Conception et visualisation de regulateurs PID';
            app.text26 = uilabel(app.figure1);
            app.text26.Text = ' © Carvalho Esteban | Usage pedagogique';
            app.uipanel21 = uipanel(app.figure1);
            app.uipanel21.Title = 'Performances';
            app.text37 = uilabel(app.uipanel21);
            app.text37.Text = 'Temps de reponse à 5%';
            app.text38 = uilabel(app.uipanel21);
            app.text38.Text = 'Erreur statique';
            app.tr5_txt = uieditfield(app.uipanel21, 'text');
            app.tr5_txt.Enable = 'off';
            app.err_txt = uieditfield(app.uipanel21, 'text');
            app.err_txt.Enable = 'off';
            app.text39 = uilabel(app.uipanel21);
            app.text39.Text = 'Depassement';
            app.dep_txt = uieditfield(app.uipanel21, 'text');
            app.dep_txt.Enable = 'off';
            app.text65 = uilabel(app.uipanel21);
            app.text65.Text = '(s)';
            app.text65_2 = uilabel(app.uipanel21);
            app.text65_2.Text = ' (%)';
            app.LangageSwitchLabel = uilabel(app.figure1);
            app.LangageSwitchLabel.Text = 'Langage';
            app.LangageSwitch = uiswitch(app.figure1, 'slider');
            app.LangageSwitch.Items = {'FR', 'EN'};
            app.LangageSwitch.ValueChangedFcn = createCallbackFcn(app, @LangageSwitchValueChanged, true);
            app.LangageSwitch.Value = 'FR';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PID_visu

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @Controllers_App_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end