classdef Pole_placement < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PIDPolePlacementUIFigure   matlab.ui.Figure
        UIAxes                     matlab.ui.control.UIAxes
        uipanel2                   matlab.ui.container.Panel
        SystemorderDropDownLabel   matlab.ui.control.Label
        SystemorderDropDown        matlab.ui.control.DropDown
        ParametersPanel_2          matlab.ui.container.Panel
        KEditField_2Label          matlab.ui.control.Label
        KEditField                 matlab.ui.control.NumericEditField
        T1EditFieldLabel           matlab.ui.control.Label
        T1EditField                matlab.ui.control.NumericEditField
        T2EditFieldLabel           matlab.ui.control.Label
        T2EditField                matlab.ui.control.NumericEditField
        panel_4                    matlab.ui.container.Panel
        text_k_10                  matlab.ui.control.Label
        text_k_11                  matlab.ui.control.Label
        uipanel4_4                 matlab.ui.container.Panel
        uipanel5_4                 matlab.ui.container.Panel
        text_k_12                  matlab.ui.control.Label
        panel_8                    matlab.ui.container.Panel
        text_k_20                  matlab.ui.control.Label
        text_k_21                  matlab.ui.control.Label
        uipanel4_7                 matlab.ui.container.Panel
        uipanel5_7                 matlab.ui.container.Panel
        text_k_22                  matlab.ui.control.Label
        ControllerPanel            matlab.ui.container.Panel
        ControllerDropDownLabel    matlab.ui.control.Label
        ControllerDropDown         matlab.ui.control.DropDown
        ParametersPanel            matlab.ui.container.Panel
        CEditFieldLabel            matlab.ui.control.Label
        CEditField                 matlab.ui.control.NumericEditField
        TiEditFieldLabel           matlab.ui.control.Label
        TiEditField                matlab.ui.control.NumericEditField
        TdEditFieldLabel           matlab.ui.control.Label
        TdEditField                matlab.ui.control.NumericEditField
        panel_5                    matlab.ui.container.Panel
        uipanel4_5                 matlab.ui.container.Panel
        text_k_13                  matlab.ui.control.Label
        uipanel5_5                 matlab.ui.container.Panel
        text_k_15                  matlab.ui.control.Label
        text_k_14                  matlab.ui.control.Label
        panel_7                    matlab.ui.container.Panel
        text_k_19                  matlab.ui.control.Label
        panel_6                    matlab.ui.container.Panel
        uipanel4_6                 matlab.ui.container.Panel
        text_k_16                  matlab.ui.control.Label
        uipanel5_6                 matlab.ui.container.Panel
        text_k_18                  matlab.ui.control.Label
        text_k_17                  matlab.ui.control.Label
        DesiredPerformancesPanel   matlab.ui.container.Panel
        TimecriteriaDropDownLabel  matlab.ui.control.Label
        TimecriteriaDropDown       matlab.ui.control.DropDown
        StaticgainEditFieldLabel   matlab.ui.control.Label
        StaticgainEditField        matlab.ui.control.NumericEditField
        OvershootEditFieldLabel    matlab.ui.control.Label
        OvershootEditField         matlab.ui.control.NumericEditField
        sEditFieldLabel            matlab.ui.control.Label
        TimeEditField              matlab.ui.control.NumericEditField
        CheckBox                   matlab.ui.control.CheckBox
        CheckBox_2                 matlab.ui.control.CheckBox
        Label                      matlab.ui.control.Label
        Label_2                    matlab.ui.control.Label
        withinEditFieldLabel       matlab.ui.control.Label
        withinEditField            matlab.ui.control.NumericEditField
        AchievedPerformancesPanel  matlab.ui.container.Panel
        SettlingtimeLabel          matlab.ui.control.Label
        ST                         matlab.ui.control.NumericEditField
        RisetimeLabel              matlab.ui.control.Label
        RT                         matlab.ui.control.NumericEditField
        OvershootLabel             matlab.ui.control.Label
        OV                         matlab.ui.control.NumericEditField
        SteadystateerrorLabel      matlab.ui.control.Label
        SSE                        matlab.ui.control.NumericEditField
        ST_limite                  matlab.ui.control.Label
        Label_3                    matlab.ui.control.Label
        sLabel                     matlab.ui.control.Label
        sLabel_2                   matlab.ui.control.Label
        text25                     matlab.ui.control.Label
        text26                     matlab.ui.control.Label
        Image                      matlab.ui.control.Image
    end

    
    properties (Access = private)
        system      % Système étudié
        controller  % Contrôleur utilisé
        help_msg    % Figure associée aux messages d'aide
    end
    
    methods (Access = private)
        
        function [K, T1, T2] = get_system_data(app)
            % Récupère les paramètres du système
            
            K = app.system.K;
            T1 = app.system.T1;
            T2 = app.system.T2;
        end
        
        function [Kdes, Tdes, Ddes] = get_desired(app)
            % Récupère les caractéristiques désirées
            
            Kdes = app.StaticgainEditField.Value;
            Tdes = app.TimeEditField.Value;
            Ddes = app.OvershootEditField.Value;
        end
        
        function update_system(app)
            % Met à jour la fonction de transfert du système
            
            K = app.KEditField.Value;
            T1 = app.T1EditField.Value;
            T2 = app.T2EditField.Value;
            
            app.system.K = K;
            app.system.T1 = T1;
            if strcmp(app.SystemorderDropDown.Value, '1st')
                app.system.T2 = 0;
                app.system.sys = tf(K, [T1 1]);
            else
                app.system.T2 = T2;
                app.system.sys = tf(K, [T1 1])*tf(1, [T2 1]);
            end
        end
        
        function update_controller_field(app, C, Ti, Td)
            % Met à jour les composants du contrôleur
            app.CEditField.Value = C;
            app.TiEditField.Value = Ti;
            app.TdEditField.Value = Td;
        end
        
        function update_controller(app)
            % Met à jour la fonction de transfert du contrôleur
            C = app.CEditField.Value;
            Ti = app.TiEditField.Value;
            Td = app.TdEditField.Value;

            app.controller.C = C;
            app.controller.Ti = Ti;
            app.controller.Td = Td;

            if strcmp(app.ControllerDropDown.Value, 'P')
                ctr = C;
            elseif strcmp(app.ControllerDropDown.Value, 'PI')
                ctr = C*tf([Ti 1], [Ti 0]);
            else
                ctr = C*tf([Ti*Td Ti+Td 1], [Ti 0]);
            end
            app.controller.ctr = ctr;
        end
        
        function update_ui(app)
            % Met à jour l'application
            
            % Gère l'affichage du la boîte d'aide
            app.close_msgbox();
            
            % Gère les autres affichages sur la figure
            if strcmp(app.SystemorderDropDown.Value, '1st')
                app.panel_8.Visible = 'on';
                app.panel_4.Visible = 'off';
                app.T2EditField.Visible = 'off';
                app.T2EditFieldLabel.Visible = 'off';
                app.ControllerDropDown.Items = {'P', 'PI', 'PID'};
            else
                app.panel_8.Visible = 'off';
                app.panel_4.Visible = 'on';
                app.T2EditField.Visible = 'on';
                app.T2EditFieldLabel.Visible = 'on';
                app.ControllerDropDown.Items = {'PID'};
            end

            if strcmp(app.ControllerDropDown.Value, 'P')
                app.OvershootEditField.Visible = 'off';
                app.OvershootEditFieldLabel.Visible = 'off';
                app.CheckBox_2.Visible = 'on';
                app.CheckBox.Visible = 'on';
                app.StaticgainEditField.Visible = 'on';
                app.StaticgainEditFieldLabel.Visible = 'on';
                app.panel_7.Visible ='on';
                app.panel_6.Visible ='off';
                app.panel_5.Visible ='off';
                app.TiEditField.Visible ='off';
                app.TiEditFieldLabel.Visible ='off';
                app.TdEditField.Visible ='off';
                app.TdEditFieldLabel.Visible ='off';
                app.Label_2.Visible = 'off';
            else 
                app.OvershootEditField.Visible = 'on';
                app.OvershootEditFieldLabel.Visible = 'on';
                app.CheckBox_2.Visible = 'off';
                app.CheckBox.Visible = 'off';
                app.StaticgainEditField.Visible = 'off';
                app.StaticgainEditFieldLabel.Visible = 'off';
                app.Label_2.Visible = 'on';
                
                if strcmp(app.ControllerDropDown.Value, 'PI')
                    app.panel_7.Visible ='off';
                    app.panel_6.Visible ='on';
                    app.panel_5.Visible ='off';
                    app.TiEditField.Visible ='on';
                    app.TiEditFieldLabel.Visible ='on';
                    app.TdEditField.Visible ='off';
                    app.TdEditFieldLabel.Visible ='off';
                    
                else
                    app.panel_7.Visible ='off';
                    app.panel_6.Visible ='off';
                    app.panel_5.Visible ='on';
                    app.TiEditField.Visible ='on';
                    app.TiEditFieldLabel.Visible ='on';
                    app.TdEditField.Visible ='on';
                    app.TdEditFieldLabel.Visible ='on';
                end
            end
            
            app.ST_limite.Text = ['within ', num2str(app.withinEditField.Value),' %'];

            update_figure(app);   
        end
        
        function update_figure(app)
            % Met à jour la figure
            app.update_system()
            [K, T1, T2] = get_system_data(app);
            [Kdes, Tdes, Ddes] = get_desired(app);
            
            % Cas du système du premier ordre
            if strcmp(app.SystemorderDropDown.Value, '1st')
                
                % Cas contrôleur proportionnel
                if strcmp(app.ControllerDropDown.Value, 'P')
                    
                    % Cas en fonction des critères
                    if app.CheckBox.Value
                       % Temps de réponse 5%
                       if strcmp(app.TimecriteriaDropDown.Value,'Settling time')
                            C = (-log(app.withinEditField.Value/100)*T1-Tdes)/(K*Tdes);

                       elseif strcmp(app.TimecriteriaDropDown.Value,'Rise time')
                           C = ((log(0.9)-log(0.1))*T1-Tdes)/(K*Tdes);
                       end
                    elseif app.CheckBox_2.Value
                        % Gain statique
                        if Kdes~=1 && Kdes~=0
                            C = -Kdes/(K*(Kdes-1));
                        elseif Kdes==1
                            Kdes = 0.9999;
                            C = -Kdes/(K*(Kdes-1));
                        elseif Kdes==0
                            Kdes = eps;
                            C = -Kdes/(K*(Kdes-1));
                        end
                    
                    end
                    Ti = 0;
                    Td = 0;
                end
                
                % Cas contrôleur proportionnel integrale
                if strcmp(app.ControllerDropDown.Value, 'PI') || strcmp(app.ControllerDropDown.Value, 'PID')
                    
                    % Cas en fonction des critères
                    if app.OvershootEditField.Value == 0
                        % Temps de réponse
                        Ti = T1;
                        if strcmp(app.TimecriteriaDropDown.Value, 'Settling time')
                            C = -log(app.withinEditField.Value/100)*Ti/(K*Tdes);
                        elseif strcmp(app.TimecriteriaDropDown.Value, 'Rise time')
                            C = (log(0.9)-log(0.1))*Ti/(K*Tdes);
                        end
                    else
                        syms x;
                        xi = double(solve(Ddes==exp(-x*pi/sqrt(1-x^2))*100, x, 'Real', true));
                        if strcmp(app.TimecriteriaDropDown.Value, 'Settling time')
                            wn = -log(app.withinEditField.Value/100)/(xi*Tdes);
                        else
                            wn = (2.16*xi+0.6)/Tdes;
                        end
                        C = (2*xi*wn*T1-1)/K;
                        Ti = K*C/(wn^2*T1);
                    end
                    Td = 0;
                end
            end
            
            % Cas système du second ordre ordre
            if strcmp(app.SystemorderDropDown.Value, '2nd')
 
%                 if strcmp(app.ControllerDropDown.Value, 'PI')
%                     Ti = max(T1, T2);
%                     Tint = min(T1, T2);
%                     % Cas en fonction des criteres
%                     if app.CheckBox.Value
%                        % Temps de reponse 5%
%                        
%                        if strcmp(app.TimecriteriaDropDown.Value, 'Settling time')
%                             wn = -log(app.withinEditField.Value/100)/(xi*Tdes);
%                        else
%                             wn = (2.16*xi+0.6)/Tdes;
%                        end                          
                Ti = max(T1, T2);
                Td = min(T1, T2);
                if strcmp(app.TimecriteriaDropDown.Value, 'Settling time')
                        C = -log(app.withinEditField.Value/100)*Ti/(K*Tdes);
                elseif strcmp(app.TimecriteriaDropDown.Value, 'Rise time')
                        C = (log(0.9)-log(0.1))*Ti/(K*Tdes);
                end

            end
            
            % Met à jour l'interface et la variable controller
            app.update_controller_field(C, Ti, Td)
            app.update_controller()
            
            % Calcul de la boucle fermée
            closed_loop = feedback(app.system.sys*app.controller.ctr, 1);
            [y, t] = step(closed_loop);
            xlim(app.UIAxes, [0, t(end)])
            ylim(app.UIAxes, [min(min(y(:,:,1)))*1.01, max(max(y(:,:,1)))*1.1])
            plot(app.UIAxes, t, y(:,:,1));
            grid(app.UIAxes, 'on');
            all_info = stepinfo(closed_loop,'SettleTimeThreshold', app.withinEditField.Value/100);
            
            if ~isnan(all_info.SettlingTime) || ~isnan(all_info.RiseTime)|| ~isnan(all_info.Overshoot)
                app.ST.Value = round(all_info.SettlingTime, 3);
                app.RT.Value = round(all_info.RiseTime, 3);
                app.OV.Value = round(all_info.Overshoot, 3);
                app.SSE.Value = round(abs(1-y(end, end, 1)), 3);
            end
            app.UIAxes.Title.String = 'Closed Loop Step Response';
        end
        
        function close_msgbox(app)
            try
                % La figure existe
                if isvalid(app.help_msg)
                    close(app.help_msg)
                end
            catch
                % Le delete handle n'existe pas encore
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.help_msg = 0;
            update_ui(app);
        end

        % Value changed function: ControllerDropDown
        function ControllerDropDownValueChanged(app, event)
            update_ui(app);
        end

        % Value changed function: TimeEditField
        function TimeEditFieldValueChanged(app, event)
            value = app.TimeEditField.Value;
            if value <= eps
                value = eps;
            end
            app.TimeEditField.Value = value;
            update_figure(app);            
        end

        % Value changed function: StaticgainEditField
        function StaticgainEditFieldValueChanged(app, event)
            if app.StaticgainEditField.Value <= eps
                app.StaticgainEditField.Value = eps;
            end
            update_figure(app);
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            app.CheckBox_2.Value = ~app.CheckBox.Value;
            update_figure(app);            
        end

        % Value changed function: CheckBox_2
        function CheckBox_2ValueChanged(app, event)
            app.CheckBox.Value = ~app.CheckBox_2.Value;
            update_figure(app);            
        end

        % Value changed function: OvershootEditField
        function OvershootEditFieldValueChanged(app, event)
            if app.OvershootEditField.Value >= 100
               app.OvershootEditField.Value = 99;
            end
            if app.OvershootEditField.Value < 0
               app.OvershootEditField.Value = 0;
            end
            update_figure(app);
        end

        % Value changed function: SystemorderDropDown
        function SystemorderDropDownValueChanged(app, event)
            update_ui(app);         
        end

        % Value changed function: TimecriteriaDropDown
        function TimecriteriaDropDownValueChanged(app, event)
            update_ui(app);
            if strcmp(app.TimecriteriaDropDown.Value, 'Settling time')
                app.withinEditField.Visible ='on';
                app.withinEditFieldLabel.Visible ='on';
                app.Label.Visible = 'on';
            else
                app.withinEditFieldLabel.Visible ='off';
                app.withinEditField.Visible ='off';
                app.Label.Visible = 'off';
            end

        end

        % Callback function
        function ThresholdValueChanged(app, event)
            update_ui(app);            
        end

        % Callback function
        function ThresholdValueChanged2(app, event)
            update_ui(app) 
        end

        % Value changed function: KEditField
        function KEditFieldValueChanged(app, event)
            value = app.KEditField.Value;
            if value <= eps
                value = eps;
            end
            app.KEditField.Value = value;
            update_figure(app)
        end

        % Value changed function: T1EditField
        function T1EditFieldValueChanged(app, event)
            value = app.T1EditField.Value;
            if value <= eps
                value = eps;
            end
            app.T1EditField.Value = value;
            update_figure(app)             
        end

        % Value changed function: T2EditField
        function T2EditFieldValueChanged(app, event)
            value = app.T2EditField.Value;
            if value <= eps
                value = eps;
            end
            app.T2EditField.Value = value;
            update_figure(app)
        end

        % Value changed function: withinEditField
        function withinEditFieldValueChanged(app, event)
            value = app.withinEditField.Value;
            if value <= 1
                value = 1;
            elseif value >= 100
                value = 100-eps;
            end
            app.withinEditField.Value = value;
            app.update_ui
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            app.close_msgbox();
            app.help_msg = msgbox(['You cannot edit controller parameters manually.', ...
                        'Parameters are automatically calculated according to the desired performances (see panel below).'], ...
                        'Info on "Controller Parameters" panel','help');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PIDPolePlacementUIFigure = uifigure('Visible', 'off');
            app.PIDPolePlacementUIFigure.Name = 'PID : Pole Placement';
            app.UIAxes = uiaxes(app.PIDPolePlacementUIFigure);
            title(app.UIAxes, 'Closed Loop Step Response')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, '')
            app.uipanel2 = uipanel(app.PIDPolePlacementUIFigure);
            app.uipanel2.Title = 'System';
            app.SystemorderDropDownLabel = uilabel(app.uipanel2);
            app.SystemorderDropDownLabel.Text = 'System order';
            app.SystemorderDropDown = uidropdown(app.uipanel2);
            app.SystemorderDropDown.Items = {'1st', '2nd'};
            app.SystemorderDropDown.ValueChangedFcn = createCallbackFcn(app, @SystemorderDropDownValueChanged, true);
            app.SystemorderDropDown.Value = '1st';
            app.ParametersPanel_2 = uipanel(app.uipanel2);
            app.ParametersPanel_2.Title = 'Parameters';
            app.KEditField_2Label = uilabel(app.ParametersPanel_2);
            app.KEditField_2Label.Text = 'K';
            app.KEditField = uieditfield(app.ParametersPanel_2, 'numeric');
            app.KEditField.ValueChangedFcn = createCallbackFcn(app, @KEditFieldValueChanged, true);
            app.KEditField.Value = 1;
            app.T1EditFieldLabel = uilabel(app.ParametersPanel_2);
            app.T1EditFieldLabel.Text = 'T1';
            app.T1EditField = uieditfield(app.ParametersPanel_2, 'numeric');
            app.T1EditField.ValueChangedFcn = createCallbackFcn(app, @T1EditFieldValueChanged, true);
            app.T1EditField.Value = 1;
            app.T2EditFieldLabel = uilabel(app.ParametersPanel_2);
            app.T2EditFieldLabel.Text = 'T2';
            app.T2EditField = uieditfield(app.ParametersPanel_2, 'numeric');
            app.T2EditField.ValueChangedFcn = createCallbackFcn(app, @T2EditFieldValueChanged, true);
            app.T2EditField.Value = 1;
            app.panel_4 = uipanel(app.uipanel2);
            app.panel_4.Title = 'Transfert Function';
            app.text_k_10 = uilabel(app.panel_4);
            app.text_k_10.Text = 'K';
            app.text_k_11 = uilabel(app.panel_4);
            app.text_k_11.Text = 'G(p) = ';
            app.uipanel4_4 = uipanel(app.panel_4);
            app.uipanel5_4 = uipanel(app.panel_4);
            app.text_k_12 = uilabel(app.panel_4);
            app.text_k_12.Text = '(1 + T1 · p) (1 + T2 · p)';
            app.panel_8 = uipanel(app.uipanel2);
            app.panel_8.Title = 'Transfert Function';
            app.text_k_20 = uilabel(app.panel_8);
            app.text_k_20.Text = 'K';
            app.text_k_21 = uilabel(app.panel_8);
            app.text_k_21.Text = 'G(p) = ';
            app.uipanel4_7 = uipanel(app.panel_8);
            app.uipanel5_7 = uipanel(app.panel_8);
            app.text_k_22 = uilabel(app.panel_8);
            app.text_k_22.Text = '(1 + T1 · p)';
            app.ControllerPanel = uipanel(app.PIDPolePlacementUIFigure);
            app.ControllerPanel.Title = 'Controller';
            app.ControllerDropDownLabel = uilabel(app.ControllerPanel);
            app.ControllerDropDownLabel.Text = 'Controller';
            app.ControllerDropDown = uidropdown(app.ControllerPanel);
            app.ControllerDropDown.Items = {'P', 'PI', 'PID'};
            app.ControllerDropDown.ValueChangedFcn = createCallbackFcn(app, @ControllerDropDownValueChanged, true);
            app.ControllerDropDown.Value = 'P';
            app.ParametersPanel = uipanel(app.ControllerPanel);
            app.ParametersPanel.Title = 'Parameters';
            app.CEditFieldLabel = uilabel(app.ParametersPanel);
            app.CEditFieldLabel.Text = 'C';
            app.CEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.CEditField.Editable = 'off';
            app.CEditField.Value = 1;
            app.TiEditFieldLabel = uilabel(app.ParametersPanel);
            app.TiEditFieldLabel.Text = 'Ti';
            app.TiEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.TiEditField.Editable = 'off';
            app.TdEditFieldLabel = uilabel(app.ParametersPanel);
            app.TdEditFieldLabel.Text = 'Td';
            app.TdEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.TdEditField.Editable = 'off';
            app.panel_5 = uipanel(app.ControllerPanel);
            app.panel_5.Title = 'Transfert Function';
            app.uipanel4_5 = uipanel(app.panel_5);
            app.text_k_13 = uilabel(app.panel_5);
            app.text_k_13.Text = 'C(p) = C ';
            app.uipanel5_5 = uipanel(app.panel_5);
            app.text_k_15 = uilabel(app.uipanel5_5);
            app.text_k_15.Text = 'Ti · p';
            app.text_k_14 = uilabel(app.panel_5);
            app.text_k_14.Text = '(1 + Ti · p) (1 + Td · p)';
            app.panel_7 = uipanel(app.ControllerPanel);
            app.panel_7.Title = 'Transfert Function';
            app.text_k_19 = uilabel(app.panel_7);
            app.text_k_19.Text = 'C(p) = C ';
            app.panel_6 = uipanel(app.ControllerPanel);
            app.panel_6.Title = 'Transfert Function';
            app.uipanel4_6 = uipanel(app.panel_6);
            app.text_k_16 = uilabel(app.panel_6);
            app.text_k_16.Text = 'C(p) = C ';
            app.uipanel5_6 = uipanel(app.panel_6);
            app.text_k_18 = uilabel(app.uipanel5_6);
            app.text_k_18.Text = 'Ti · p';
            app.text_k_17 = uilabel(app.panel_6);
            app.text_k_17.Text = '(1 + Ti · p) ';
            app.DesiredPerformancesPanel = uipanel(app.PIDPolePlacementUIFigure);
            app.DesiredPerformancesPanel.Title = 'Desired Performances';
            app.TimecriteriaDropDownLabel = uilabel(app.DesiredPerformancesPanel);
            app.TimecriteriaDropDownLabel.Text = 'Time criteria';
            app.TimecriteriaDropDown = uidropdown(app.DesiredPerformancesPanel);
            app.TimecriteriaDropDown.Items = {'Settling time', 'Rise time'};
            app.TimecriteriaDropDown.ValueChangedFcn = createCallbackFcn(app, @TimecriteriaDropDownValueChanged, true);
            app.TimecriteriaDropDown.Value = 'Settling time';
            app.StaticgainEditFieldLabel = uilabel(app.DesiredPerformancesPanel);
            app.StaticgainEditFieldLabel.Text = 'Static gain';
            app.StaticgainEditField = uieditfield(app.DesiredPerformancesPanel, 'numeric');
            app.StaticgainEditField.ValueChangedFcn = createCallbackFcn(app, @StaticgainEditFieldValueChanged, true);
            app.OvershootEditFieldLabel = uilabel(app.DesiredPerformancesPanel);
            app.OvershootEditFieldLabel.Text = 'Overshoot';
            app.OvershootEditField = uieditfield(app.DesiredPerformancesPanel, 'numeric');
            app.OvershootEditField.ValueChangedFcn = createCallbackFcn(app, @OvershootEditFieldValueChanged, true);
            app.sEditFieldLabel = uilabel(app.DesiredPerformancesPanel);
            app.sEditFieldLabel.Text = '(s)';
            app.TimeEditField = uieditfield(app.DesiredPerformancesPanel, 'numeric');
            app.TimeEditField.ValueChangedFcn = createCallbackFcn(app, @TimeEditFieldValueChanged, true);
            app.TimeEditField.Value = 1;
            app.CheckBox = uicheckbox(app.DesiredPerformancesPanel);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = '';
            app.CheckBox.Value = true;
            app.CheckBox_2 = uicheckbox(app.DesiredPerformancesPanel);
            app.CheckBox_2.ValueChangedFcn = createCallbackFcn(app, @CheckBox_2ValueChanged, true);
            app.CheckBox_2.Text = '';
            app.Label = uilabel(app.DesiredPerformancesPanel);
            app.Label.Text = '%';
            app.Label_2 = uilabel(app.DesiredPerformancesPanel);
            app.Label_2.Text = '(%)';
            app.withinEditFieldLabel = uilabel(app.DesiredPerformancesPanel);
            app.withinEditFieldLabel.Text = 'within';
            app.withinEditField = uieditfield(app.DesiredPerformancesPanel, 'numeric');
            app.withinEditField.ValueChangedFcn = createCallbackFcn(app, @withinEditFieldValueChanged, true);
            app.withinEditField.Value = 5;
            app.AchievedPerformancesPanel = uipanel(app.PIDPolePlacementUIFigure);
            app.AchievedPerformancesPanel.Title = 'Achieved Performances';
            app.SettlingtimeLabel = uilabel(app.AchievedPerformancesPanel);
            app.SettlingtimeLabel.Text = 'Settling time';
            app.ST = uieditfield(app.AchievedPerformancesPanel, 'numeric');
            app.ST.Editable = 'off';
            app.ST.Value = 1;
            app.RisetimeLabel = uilabel(app.AchievedPerformancesPanel);
            app.RisetimeLabel.Text = 'Rise time';
            app.RT = uieditfield(app.AchievedPerformancesPanel, 'numeric');
            app.RT.Editable = 'off';
            app.RT.Value = 1;
            app.OvershootLabel = uilabel(app.AchievedPerformancesPanel);
            app.OvershootLabel.Text = 'Overshoot';
            app.OV = uieditfield(app.AchievedPerformancesPanel, 'numeric');
            app.OV.Editable = 'off';
            app.OV.Value = 1;
            app.SteadystateerrorLabel = uilabel(app.AchievedPerformancesPanel);
            app.SteadystateerrorLabel.Text = 'Steady state error';
            app.SSE = uieditfield(app.AchievedPerformancesPanel, 'numeric');
            app.SSE.Editable = 'off';
            app.SSE.Value = 1;
            app.ST_limite = uilabel(app.AchievedPerformancesPanel);
            app.ST_limite.Text = 'within 5 %';
            app.Label_3 = uilabel(app.AchievedPerformancesPanel);
            app.Label_3.Text = '(%)';
            app.sLabel = uilabel(app.AchievedPerformancesPanel);
            app.sLabel.Text = '(s)';
            app.sLabel_2 = uilabel(app.AchievedPerformancesPanel);
            app.sLabel_2.Text = '(s)';
            app.text25 = uilabel(app.PIDPolePlacementUIFigure);
            app.text25.Text = 'PID : Pole placement';
            app.text26 = uilabel(app.PIDPolePlacementUIFigure);
            app.text26.Text = ' © Carvalho Esteban | Pedagogical use | 2024';
            app.Image = uiimage(app.PIDPolePlacementUIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = 'info.png';
            % Show the figure after all components are created
            app.PIDPolePlacementUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Pole_placement

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PIDPolePlacementUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PIDPolePlacementUIFigure)
        end
    end
end