classdef options < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        UIAxes                      matlab.ui.control.UIAxes
        InvertedPendulumParametersPanel  matlab.ui.container.Panel
        ArmlengthEditFieldLabel     matlab.ui.control.Label
        ArmlengthEditField          matlab.ui.control.NumericEditField
        PendulummassEditFieldLabel  matlab.ui.control.Label
        PendulummassEditField       matlab.ui.control.NumericEditField
        CartmassEditFieldLabel      matlab.ui.control.Label
        CartmassEditField           matlab.ui.control.NumericEditField
        CartdampingEditFieldLabel   matlab.ui.control.Label
        CartdampingEditField        matlab.ui.control.NumericEditField
        SaveButton                  matlab.ui.control.Button
        DiscareButton               matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp % Description
        tempCart % Description
    end
    
    methods (Access = private)
        
        function disp_cart(app)
            x = 0;
            theta = pi;
            
            % Get data from struct
            al = app.CallingApp.cartpole.pendulum.arm_length;
            mr = app.CallingApp.cartpole.pendulum.mass_radius;
            cw = app.CallingApp.cartpole.cart.cart_width; 
            ch = app.CallingApp.cartpole.cart.cart_height; 
            wr = app.CallingApp.cartpole.cart.wheel_radius; 
            
            y = wr/2+ch/2;
            
            % Positions of elements
            cartx1 = x-cw/2;
            cartx2 = y-ch/2;
            carty1 = cw;
            carty2 = ch;
            cart_pos = [cartx1 cartx2 carty1 carty2];
            pend_pos1 = [x x+al*sin(theta)];
            pend_pos2 = [wr/2+ch/2 wr/2+ch/2 - al*cos(theta)];
            wheel1_pos = [x-cw/2 0 wr wr];
            wheel2_pos = [x+cw/2-wr 0 wr wr];
            mass_pos = [x+al*sin(theta)-mr/2 y-al*cos(theta)-mr/2 mr mr];
            
            % Clear previous drawing
            cla(app.UIAxes)
            hold(app.UIAxes,'on');
            
            % Draw cart
            rectangle(app.UIAxes, 'Position', cart_pos, 'Curvature', 0.2, 'EdgeColor', 'none', 'FaceColor', [0.4 0.5 1]);
            
            % Draw wheels
            rectangle(app.UIAxes,'Position', wheel1_pos, 'Curvature',1,'FaceColor',[0 0 0],'LineWidth',1.5); 
            rectangle(app.UIAxes,'Position', wheel2_pos,'Curvature',1,'FaceColor',[0 0 0],'LineWidth',1.5); 
            
            % Draw pendulum
            line(app.UIAxes, pend_pos1, pend_pos2,'LineWidth',5, 'Color',[0.72 0.45 0.33]); 
            
            % Draw pendulum mass
            rectangle(app.UIAxes,'Position',mass_pos,'Curvature',1,'FaceColor',[0.75 0.75 0.75], 'EdgeColor', [0.75 0.75 0.75]);

            axis(app.UIAxes, 'equal')
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Inverted_pendulum)
            app.CallingApp = Inverted_pendulum;
            app.UIAxes.Toolbar.Visible = 'off';
            app.ArmlengthEditField.Value = app.CallingApp.cartpole.pendulum.arm_length;
            app.CartdampingEditField.Value = app.CallingApp.cartpole.cart.damping;
            app.PendulummassEditField.Value = app.CallingApp.cartpole.pendulum.mass;
            app.CartmassEditField.Value = app.CallingApp.cartpole.cart.mass;
            
            disp_cart(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.Angle.Enable = 'on';
            app.CallingApp.Position.Enable = 'on';
            app.CallingApp.Time.Enable = 'on';
            app.CallingApp.ControlButton.Enable = 'on';
            app.CallingApp.StartButton.Enable = 'on';
            app.CallingApp.Options.Enable = 'on';
            
            delete(app)
            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            app.CallingApp.cartpole.pendulum.arm_length = app.ArmlengthEditField.Value;
            app.CallingApp.cartpole.cart.damping = app.CartdampingEditField.Value;
            app.CallingApp.cartpole.pendulum.mass =app.PendulummassEditField.Value;
            app.CallingApp.cartpole.cart.mass = app.CartmassEditField.Value;
            disp_cart(app)
            
            x0 = app.CallingApp.Position.Value;
            theta0 = app.CallingApp.Angle.Value;
            disp_cartpole(app.CallingApp, [x0;0;theta0;0])
        end

        % Button pushed function: DiscareButton
        function DiscareButtonPushed(app, event)
            app.ArmlengthEditField.Value = app.CallingApp.cartpole.pendulum.arm_length;
            app.CartdampingEditField.Value = app.CallingApp.cartpole.cart.damping;
            app.PendulummassEditField.Value = app.CallingApp.cartpole.pendulum.mass;
            app.CartmassEditField.Value = app.CallingApp.cartpole.cart.mass;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XLim = [-5 5];
            app.InvertedPendulumParametersPanel = uipanel(app.UIFigure);
            app.InvertedPendulumParametersPanel.Title = 'Inverted Pendulum Parameters';
            app.ArmlengthEditFieldLabel = uilabel(app.InvertedPendulumParametersPanel);
            app.ArmlengthEditFieldLabel.Text = 'Arm length';
            app.ArmlengthEditField = uieditfield(app.InvertedPendulumParametersPanel, 'numeric');
            app.PendulummassEditFieldLabel = uilabel(app.InvertedPendulumParametersPanel);
            app.PendulummassEditFieldLabel.Text = 'Pendulum mass';
            app.PendulummassEditField = uieditfield(app.InvertedPendulumParametersPanel, 'numeric');
            app.CartmassEditFieldLabel = uilabel(app.InvertedPendulumParametersPanel);
            app.CartmassEditFieldLabel.Text = 'Cart mass';
            app.CartmassEditField = uieditfield(app.InvertedPendulumParametersPanel, 'numeric');
            app.CartdampingEditFieldLabel = uilabel(app.InvertedPendulumParametersPanel);
            app.CartdampingEditFieldLabel.Text = 'Cart damping';
            app.CartdampingEditField = uieditfield(app.InvertedPendulumParametersPanel, 'numeric');
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.DiscareButton = uibutton(app.UIFigure, 'push');
            app.DiscareButton.ButtonPushedFcn = createCallbackFcn(app, @DiscareButtonPushed, true);
            app.DiscareButton.Text = 'Discare';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = options(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end